classdef BitPlayer_2P < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_2PUIFigure    matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        Load2P                  matlab.ui.control.Button
        ROI_Plus                matlab.ui.control.Button
        ROI_Minus               matlab.ui.control.Button
        ROIEditFieldLabel       matlab.ui.control.Label
        n_ROI                   matlab.ui.control.NumericEditField
        SelectLabel             matlab.ui.control.Label
        SelectROIs              matlab.ui.control.EditField
        DeselectEditFieldLabel  matlab.ui.control.Label
        DeselectROIs            matlab.ui.control.EditField
        HzEditFieldLabel        matlab.ui.control.Label
        HzEditField             matlab.ui.control.NumericEditField
        F0framesEditFieldLabel  matlab.ui.control.Label
        F0frames                matlab.ui.control.EditField
        ApplyChanges            matlab.ui.control.StateButton
        Detrend                 matlab.ui.control.StateButton
        Lowcutfileter           matlab.ui.control.StateButton
        Offset                  matlab.ui.control.StateButton
        Zscore                  matlab.ui.control.StateButton
        AveragedbyStim          matlab.ui.control.Button
        ShowSelected            matlab.ui.control.Button
        ShowColormap            matlab.ui.control.Button
        ShowTuningSelected      matlab.ui.control.Button
        ShowROIMap              matlab.ui.control.Button
        Bootstrap               matlab.ui.control.Button
        FileName                matlab.ui.control.Label
        DebugButton             matlab.ui.control.Button
        Saveas                  matlab.ui.control.Button
        newbootstrapCheckBox    matlab.ui.control.CheckBox
        ImagesizeLabel          matlab.ui.control.Label
        ImageSize               matlab.ui.control.NumericEditField
        SaveFileName            matlab.ui.control.EditField
        FVsamptLabel            matlab.ui.control.Label
        FVsampt                 matlab.ui.control.NumericEditField
        Offset_2                matlab.ui.control.StateButton
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        BitPlayer_Analysys %Main app object
        
    end
    
    properties (Access = public)
        mainvar
        SaveData
        SaveTimestamps
        recobj
        sobj
        ParamsSave
        imgobj
        n = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Sharing variables with BitPlayer_Analysis.mlapp
            app.BitPlayer_Analysys = mainapp;
            
            % Set variables
            app.mainvar = mainapp.mainvar;
            app.SaveData = mainapp.SaveData;
            app.SaveTimestamps = mainapp.SaveTimestamps;
            app.recobj = mainapp.recobj;
            app.sobj = mainapp.sobj;
            app.ParamsSave = mainapp.ParamsSave;
            app.imgobj = mainapp.imgobj;
            
            if isempty(app.SaveData)
                % Reset button
                errordlg('Load DAQ & PTB first!!')
                app.BitPlayer_Analysys.Open2PWindowButton.Enable = 'on';
                app.BitPlayer_Analysys.Open2PWindowButton.Value = 0;
                delete(app)
            end
            
            %%%%%%%%%%%% %%%%%%%%%% %%%%%%%%%%
            % Check parameters
            if isfield(app.imgobj, 'F')
                app.SaveFileName.Value = app.mainvar.fname_daq;
                app.FileName.Text = ['File: ', app.mainvar.dirname_daq,...
                    app.mainvar.fname_daq];
                
                % imaging data is alread extracted.
                
%                 %Get_stim_timing
%                 Get_stim_timing(app);
%                 
%                 %plot check
%                 if ~isempty(app.imgobj.dFF)
%                     Plot_dFF_next(app, 0)
%                     Plot_dFF_selectROIs(app)
%                 end
%                 
%                 
%                 %F0 check
%                 if isfield(app.imgobj, 'f0')
%                     app.F0_end.Value = app.imgobj.f0;
%                 else
%                     errordlg('F0 not defined')
%                     app.NBA_Ana11.TwoPhotonButton.Enable = 'on';
%                     delete(app)
%                 end
            end
            
        end

        % Button pushed function: Load2P
        function Load2PPushed(app, event)
            Open_load2P(app)
            
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            assignin('base', "mainvar", app.mainvar)
            assignin('base', "SaveData", app.SaveData)
            assignin('base', "SaveTimestamps", app.SaveTimestamps)
            assignin('base', "recobj", app.recobj)
            assignin('base', "sobj", app.sobj)
            assignin('base', "ParamsSave", app.ParamsSave)
            assignin('base', "imgobj", app.imgobj)
        end

        % Close request function: BitPlayer_2PUIFigure
        function BitPlayer_2PUIFigureCloseRequest(app, event)
            % Enable the Open2P button in main app
            app.BitPlayer_Analysys.Open2PWindowButton.Enable = 'on';
            app.BitPlayer_Analysys.Open2PWindowButton.Value = 0;
            
            % Delete the BitPlayer_2P App
            delete(app)
            
        end

        % Button pushed function: Saveas
        function SaveasPushed(app, event)
            Save_params2mat(app);
            app.SaveFileName.BackgroundColor = [1, 1, 1];
        end

        % Value changed function: SaveFileName
        function SaveFileNameValueChanged(app, event)
            value = app.SaveFileName.Value;
            if ~strcmp(value, app.mainvar.fname_daq)
                app.SaveFileName.BackgroundColor = [1, 0.81, 0.81];
            else
                app.SaveFileName.BackgroundColor = [1, 1, 1];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BitPlayer_2PUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {800, 800};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {397, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_2PUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_2PUIFigure.Name = 'BitPlayer_2P';
            app.BitPlayer_2PUIFigure.CloseRequestFcn = createCallbackFcn(app, @BitPlayer_2PUIFigureCloseRequest, true);
            app.BitPlayer_2PUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BitPlayer_2PUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Menu';
            app.Load2P = uibutton(app.LeftPanel, 'push');
            app.Load2P.ButtonPushedFcn = createCallbackFcn(app, @Load2PPushed, true);
            app.Load2P.Text = 'Load 2P';
            app.ROI_Plus = uibutton(app.LeftPanel, 'push');
            app.ROI_Plus.Text = ' ROI +';
            app.ROI_Minus = uibutton(app.LeftPanel, 'push');
            app.ROI_Minus.Text = ' ROI -';
            app.ROIEditFieldLabel = uilabel(app.LeftPanel);
            app.ROIEditFieldLabel.Text = 'ROI #';
            app.n_ROI = uieditfield(app.LeftPanel, 'numeric');
            app.n_ROI.Limits = [1 Inf];
            app.n_ROI.Value = 1;
            app.SelectLabel = uilabel(app.LeftPanel);
            app.SelectLabel.Text = 'Select: ';
            app.SelectROIs = uieditfield(app.LeftPanel, 'text');
            app.SelectROIs.Value = '1';
            app.DeselectEditFieldLabel = uilabel(app.LeftPanel);
            app.DeselectEditFieldLabel.Text = 'Deselect:';
            app.DeselectROIs = uieditfield(app.LeftPanel, 'text');
            app.HzEditFieldLabel = uilabel(app.LeftPanel);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HzEditField.Limits = [0 Inf];
            app.HzEditField.Value = 0.005;
            app.F0framesEditFieldLabel = uilabel(app.LeftPanel);
            app.F0framesEditFieldLabel.Text = 'F0 frames: ';
            app.F0frames = uieditfield(app.LeftPanel, 'text');
            app.F0frames.Value = '1:100';
            app.ApplyChanges = uibutton(app.LeftPanel, 'state');
            app.ApplyChanges.Text = 'Apply Changes to dF/F';
            app.Detrend = uibutton(app.LeftPanel, 'state');
            app.Detrend.Text = 'Detrend';
            app.Lowcutfileter = uibutton(app.LeftPanel, 'state');
            app.Lowcutfileter.Text = 'Low cut fileter';
            app.Offset = uibutton(app.LeftPanel, 'state');
            app.Offset.Text = 'Offset';
            app.Zscore = uibutton(app.LeftPanel, 'state');
            app.Zscore.Text = 'Z-scored';
            app.AveragedbyStim = uibutton(app.LeftPanel, 'push');
            app.AveragedbyStim.Text = 'Averaged by Stim';
            app.ShowSelected = uibutton(app.LeftPanel, 'push');
            app.ShowSelected.Text = 'Show response (selected)';
            app.ShowColormap = uibutton(app.LeftPanel, 'push');
            app.ShowColormap.Text = 'Color map (All)';
            app.ShowTuningSelected = uibutton(app.LeftPanel, 'push');
            app.ShowTuningSelected.Text = 'Tuning (selected)';
            app.ShowROIMap = uibutton(app.LeftPanel, 'push');
            app.ShowROIMap.Text = 'ROI map';
            app.Bootstrap = uibutton(app.LeftPanel, 'push');
            app.Bootstrap.Text = 'Bootstrap & Fit';
            app.FileName = uilabel(app.LeftPanel);
            app.FileName.Text = 'File:';
            app.DebugButton = uibutton(app.LeftPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.Saveas = uibutton(app.LeftPanel, 'push');
            app.Saveas.ButtonPushedFcn = createCallbackFcn(app, @SaveasPushed, true);
            app.Saveas.Text = 'Save as...';
            app.newbootstrapCheckBox = uicheckbox(app.LeftPanel);
            app.newbootstrapCheckBox.Text = 'new bootstrap';
            app.ImagesizeLabel = uilabel(app.LeftPanel);
            app.ImagesizeLabel.Text = 'Image size:';
            app.ImageSize = uieditfield(app.LeftPanel, 'numeric');
            app.ImageSize.Limits = [1 Inf];
            app.ImageSize.Value = 320;
            app.SaveFileName = uieditfield(app.LeftPanel, 'text');
            app.SaveFileName.ValueChangedFcn = createCallbackFcn(app, @SaveFileNameValueChanged, true);
            app.SaveFileName.Value = '*****.mat';
            app.FVsamptLabel = uilabel(app.LeftPanel);
            app.FVsamptLabel.Text = 'FV sampt:';
            app.FVsampt = uieditfield(app.LeftPanel, 'numeric');
            app.FVsampt.Limits = [0 1];
            app.FVsampt.ValueDisplayFormat = '%11.6g';
            app.FVsampt.Value = 0.574616;
            app.Offset_2 = uibutton(app.LeftPanel, 'state');
            app.Offset_2.Text = 'Get F0';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'View 2P Data';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'ROI#  ')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'dF/F0')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Selected')
            xlabel(app.UIAxes_2, 'Time (sec)')
            ylabel(app.UIAxes_2, 'dF/F0')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.BitPlayer_2PUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_2P(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BitPlayer_2PUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BitPlayer_2PUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_2PUIFigure)
        end
    end
end