classdef BitPlayer_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        OpendaqButton                   matlab.ui.control.Button
        FileName                        matlab.ui.control.Label
        Mouse                           matlab.ui.control.Label
        Params1                         matlab.ui.control.Label
        Params2                         matlab.ui.control.Label
        PlusButton                      matlab.ui.control.Button
        MinusButton                     matlab.ui.control.Button
        TrialEditFieldLabel             matlab.ui.control.Label
        Trial_n                         matlab.ui.control.NumericEditField
        Save_to_mat                     matlab.ui.control.Button
        SaveFileNameEditFieldLabel      matlab.ui.control.Label
        SaveFileName                    matlab.ui.control.EditField
        Get_F0                          matlab.ui.control.Button
        FVsamptLabel                    matlab.ui.control.Label
        FVsampt                         matlab.ui.control.NumericEditField
        PhotosensorthresholdmVLabel     matlab.ui.control.Label
        Threshold_photo_sensor          matlab.ui.control.NumericEditField
        Get_this_event                  matlab.ui.control.Button
        Update_all_saccade              matlab.ui.control.Button
        Clear_all_saccade               matlab.ui.control.Button
        Clear_this_event                matlab.ui.control.Button
        Plot_saccade                    matlab.ui.control.Button
        Plot_all_saccade                matlab.ui.control.Button
        Open2PWindowButton              matlab.ui.control.StateButton
        Save_to_WS                      matlab.ui.control.Button
        OpenptbButton                   matlab.ui.control.Button
        StimPattern                     matlab.ui.control.Label
        Date                            matlab.ui.control.Label
        SaccadethresholdhighpixsecLabel  matlab.ui.control.Label
        Threshold_saccade_high          matlab.ui.control.NumericEditField
        SaccadethresholdlowpixsecLabel  matlab.ui.control.Label
        Threshold_saccade_low           matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        Detected_saccade                matlab.ui.control.EditField
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        SaveData
        SaveTimestamps
        recobj
        
        mainvar
        
        sobj
        ParamsSave
        
        imgobj
        
        n_in_loop = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add File Path
            % Need to use full path
            mypath = '/home/lattice/Research/BitPlayer/Analysis/';
            cd(mypath);
            
            subfolders = {'IO', 'GUI'};
            for i = 1:length(subfolders)
                p = genpath([mypath, subfolders{i}]);
                addpath(p)
            end
            
            %% for python
            my_pypath = [mypath, 'Extra/Python_Mymodules/'];
            !export PYTHONPATH="$PYTHONPATH:/home/lattice/Research/BitPlayer/Analysis/Python_Mymodules/"
            pypath = py.sys.path;
            if count(pypath, my_pypath) == 0
                insert(pypath, int32(0),my_pypath);
                disp('Add PYTHONPATH')
            end
            
            py.bfloadpy.ck_load();
            
            
        end

        % Button pushed function: OpendaqButton
        function OpendaqButtonPushed(app, event)
            %app.TwoPhotonButton.Enable = 'on';
            %delete(app.O2P)
            
            app.Trial_n.Enable = 'off';
            app.PlusButton.Enable = 'off';
            app.MinusButton.Enable = 'off';
            
            Open_daq_mat(app)
            if ~isempty(app.SaveData)
                %Successfully loaded daq file
                if isempty(app.sobj)
                    Open_ptb_mat(app);
                end
            end
            
            if ~isempty(app.sobj)
                app.Trial_n.Enable = 'on';
                app.PlusButton.Enable = 'on';
                app.MinusButton.Enable = 'on';
                Plot_next(app)
            end
            
            
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            n = app.n_in_loop;
            if n < size(app.SaveData, 3)
                n = n + 1;
            else
                n = size(app.SaveData, 3);
                disp('No more trials.')
            end
            
            app.Trial_n.Value = n;
            app.n_in_loop = n;
            Plot_next(app);
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            n = app.n_in_loop;
            if n > 1
                n = n - 1;
            else
                n = 1;
                disp('This is the first trial.')
            end
            
            app.Trial_n.Value = n;
            app.n_in_loop = n;
            Plot_next(app);
        end

        % Value changed function: Trial_n
        function Trial_nValueChanged(app, event)
            n = app.Trial_n.Value;
            if n > size(app.SaveData, 3) || n < 1
                disp('n is out of range. Reset to trial#1.')
                app.n_in_loop = 1;
            else
                app.n_in_loop = n;
            end
            
            Plot_next(app);
        end

        % Value changed function: Threshold_photo_sensor
        function Threshold_photo_sensorValueChanged(app, event)
            %value = app.Threshold_photo_sensor.Value;
            if ~isempty(app.SaveData)
                Plot_next(app)
            end
        end

        % Button pushed function: OpenptbButton
        function OpenptbButtonPushed(app, event)
            Open_ptb_mat(app)
            if ~isempty(app.SaveData) && ~isempty(app.sobj)
                app.Trial_n.Enable = 'on';
                app.PlusButton.Enable = 'on';
                app.MinusButton.Enable = 'on';
            end
        end

        % Button pushed function: Save_to_WS
        function Save_to_WSButtonPushed(app, event)
            assignin('base', "app", app)
        end

        % Value changed function: Threshold_saccade_high
        function Threshold_saccade_highValueChanged(app, event)
            value = app.Threshold_saccade_high.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {800, 800};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Menu';
            app.OpendaqButton = uibutton(app.LeftPanel, 'push');
            app.OpendaqButton.ButtonPushedFcn = createCallbackFcn(app, @OpendaqButtonPushed, true);
            app.OpendaqButton.Text = 'Open daq';
            app.FileName = uilabel(app.LeftPanel);
            app.FileName.Text = 'File Name:';
            app.Mouse = uilabel(app.LeftPanel);
            app.Mouse.Text = 'Mouse: ****';
            app.Params1 = uilabel(app.LeftPanel);
            app.Params1.Text = 'Params1:';
            app.Params2 = uilabel(app.LeftPanel);
            app.Params2.Text = 'Params2:';
            app.PlusButton = uibutton(app.LeftPanel, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Enable = 'off';
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.LeftPanel, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Enable = 'off';
            app.MinusButton.Text = '-';
            app.TrialEditFieldLabel = uilabel(app.LeftPanel);
            app.TrialEditFieldLabel.Enable = 'off';
            app.TrialEditFieldLabel.Text = 'Trial  #:';
            app.Trial_n = uieditfield(app.LeftPanel, 'numeric');
            app.Trial_n.ValueChangedFcn = createCallbackFcn(app, @Trial_nValueChanged, true);
            app.Trial_n.Enable = 'off';
            app.Trial_n.Value = 1;
            app.Save_to_mat = uibutton(app.LeftPanel, 'push');
            app.Save_to_mat.Text = 'Save to File';
            app.SaveFileNameEditFieldLabel = uilabel(app.LeftPanel);
            app.SaveFileNameEditFieldLabel.Text = 'Save File Name:';
            app.SaveFileName = uieditfield(app.LeftPanel, 'text');
            app.SaveFileName.Value = '******.mat';
            app.Get_F0 = uibutton(app.LeftPanel, 'push');
            app.Get_F0.Text = 'Get F0';
            app.FVsamptLabel = uilabel(app.LeftPanel);
            app.FVsamptLabel.Text = 'FV sampt:';
            app.FVsampt = uieditfield(app.LeftPanel, 'numeric');
            app.FVsampt.ValueDisplayFormat = '%11.6g';
            app.FVsampt.Value = 0.574616;
            app.PhotosensorthresholdmVLabel = uilabel(app.LeftPanel);
            app.PhotosensorthresholdmVLabel.Text = 'Photo sensor threshold:                mV';
            app.Threshold_photo_sensor = uieditfield(app.LeftPanel, 'numeric');
            app.Threshold_photo_sensor.ValueChangedFcn = createCallbackFcn(app, @Threshold_photo_sensorValueChanged, true);
            app.Threshold_photo_sensor.Value = 60;
            app.Get_this_event = uibutton(app.LeftPanel, 'push');
            app.Get_this_event.Text = 'Get saccade';
            app.Update_all_saccade = uibutton(app.LeftPanel, 'push');
            app.Update_all_saccade.Text = 'Update all event';
            app.Clear_all_saccade = uibutton(app.LeftPanel, 'push');
            app.Clear_all_saccade.Text = 'Clear all event';
            app.Clear_this_event = uibutton(app.LeftPanel, 'push');
            app.Clear_this_event.Text = 'Clear this event';
            app.Plot_saccade = uibutton(app.LeftPanel, 'push');
            app.Plot_saccade.Text = 'Plot saccade';
            app.Plot_all_saccade = uibutton(app.LeftPanel, 'push');
            app.Plot_all_saccade.Text = 'Plot all saccades';
            app.Open2PWindowButton = uibutton(app.LeftPanel, 'state');
            app.Open2PWindowButton.Text = 'Open 2P Window';
            app.Save_to_WS = uibutton(app.LeftPanel, 'push');
            app.Save_to_WS.ButtonPushedFcn = createCallbackFcn(app, @Save_to_WSButtonPushed, true);
            app.Save_to_WS.Text = 'Debug';
            app.OpenptbButton = uibutton(app.LeftPanel, 'push');
            app.OpenptbButton.ButtonPushedFcn = createCallbackFcn(app, @OpenptbButtonPushed, true);
            app.OpenptbButton.Text = 'Open ptb';
            app.StimPattern = uilabel(app.LeftPanel);
            app.StimPattern.Text = 'Stim:';
            app.Date = uilabel(app.LeftPanel);
            app.Date.Text = 'Date: ****/**/**';
            app.SaccadethresholdhighpixsecLabel = uilabel(app.LeftPanel);
            app.SaccadethresholdhighpixsecLabel.Text = 'Saccade threshold: high              pix/sec';
            app.Threshold_saccade_high = uieditfield(app.LeftPanel, 'numeric');
            app.Threshold_saccade_high.ValueChangedFcn = createCallbackFcn(app, @Threshold_saccade_highValueChanged, true);
            app.Threshold_saccade_high.Value = 8;
            app.SaccadethresholdlowpixsecLabel = uilabel(app.LeftPanel);
            app.SaccadethresholdlowpixsecLabel.Text = 'Saccade threshold: low               pix/sec';
            app.Threshold_saccade_low = uieditfield(app.LeftPanel, 'numeric');
            app.Threshold_saccade_low.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'View Data';
            app.Detected_saccade = uieditfield(app.RightPanel, 'text');
            app.Detected_saccade.Value = '0';
            app.UIAxes_1 = uiaxes(app.RightPanel);
            title(app.UIAxes_1, 'Eye Center -- Horizontal')
            ylabel(app.UIAxes_1, 'Temp <---> Nasal')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.XTickLabel = '';
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Eye Center -- Vertical')
            ylabel(app.UIAxes_2, 'Up <---> Down')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Eye -- Velocity')
            ylabel(app.UIAxes_3, 'Pixel / sec')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YLim = [0 10];
            app.UIAxes_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Locomotion Velocity/ Eye Open Ratio')
            ylabel(app.UIAxes_4, 'deg/sec')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, 'Photo sensor -- Visual Stim Timing')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'mV')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end