classdef BitPlayer_daq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_DAQAppUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        DAQONButton               matlab.ui.control.StateButton
        SAVEOFFButton             matlab.ui.control.StateButton
        StartRECButton            matlab.ui.control.StateButton
        EXITButton                matlab.ui.control.Button
        FileNameButton            matlab.ui.control.Button
        UnsetfilenameLabel        matlab.ui.control.Label
        CenterPanel               matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        UseCameraButton           matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DAQONButton
        function DAQONButtonValueChanged(app, event)
            value = app.DAQONButton.Value;
            if value
                app.DAQONButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.DAQONButton.Text = "DAQ-ON";
            else
                app.DAQONButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.DAQONButton.Text = "DAQ-OFF";
            end
        end

        % Value changed function: SAVEOFFButton
        function SAVEOFFButtonValueChanged(app, event)
            value = app.SAVEOFFButton.Value;
            if value
                app.SAVEOFFButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.SAVEOFFButton.Text = "SAVE-ON";
            else
                app.SAVEOFFButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.SAVEOFFButton.Text = "SAVE-OFF";
            end
            
        end

        % Value changed function: StartRECButton
        function StartRECButtonValueChanged(app, event)
            value = app.StartRECButton.Value;
            if value
                app.StartRECButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.StartRECButton.Text = "Recording";
            else
                app.StartRECButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.StartRECButton.Text = "Start REC";
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BitPlayer_DAQAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {702, 702, 702};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {702, 702};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 280};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_DAQAppUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_DAQAppUIFigure.Name = 'Bit Player_DAQ.App';
            app.BitPlayer_DAQAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BitPlayer_DAQAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'DAQ';
            app.DAQONButton = uibutton(app.LeftPanel, 'state');
            app.DAQONButton.ValueChangedFcn = createCallbackFcn(app, @DAQONButtonValueChanged, true);
            app.DAQONButton.Text = 'DAQ-ON';
            app.DAQONButton.Value = true;
            app.SAVEOFFButton = uibutton(app.LeftPanel, 'state');
            app.SAVEOFFButton.ValueChangedFcn = createCallbackFcn(app, @SAVEOFFButtonValueChanged, true);
            app.SAVEOFFButton.Text = 'SAVE-OFF';
            app.StartRECButton = uibutton(app.LeftPanel, 'state');
            app.StartRECButton.ValueChangedFcn = createCallbackFcn(app, @StartRECButtonValueChanged, true);
            app.StartRECButton.Text = 'Start REC';
            app.EXITButton = uibutton(app.LeftPanel, 'push');
            app.EXITButton.Text = 'EXIT';
            app.FileNameButton = uibutton(app.LeftPanel, 'push');
            app.FileNameButton.Text = 'File Name';
            app.UnsetfilenameLabel = uilabel(app.LeftPanel);
            app.UnsetfilenameLabel.Text = 'Unset file name';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Live Plots';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Eye Horizontal (V)')
            app.UIAxes.XTick = [];
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'Eye Vertical (V)')
            app.UIAxes_2.XTick = [];
            app.UIAxes_3 = uiaxes(app.CenterPanel);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'Photo Sensor (V)')
            app.UIAxes_3.XTick = [];
            app.UIAxes_4 = uiaxes(app.CenterPanel);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, 'Rotally Angle (deg)')
            app.UIAxes_4.XTick = [];
            app.UIAxes_5 = uiaxes(app.CenterPanel);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, 'Live Trigger')
            app.UIAxes_5.XTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'IMAQ';
            app.UseCameraButton = uibutton(app.RightPanel, 'state');
            app.UseCameraButton.Text = 'Use Camera';
            % Show the figure after all components are created
            app.BitPlayer_DAQAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_daq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BitPlayer_DAQAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_DAQAppUIFigure)
        end
    end
end